classdef biopsyControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BreastBiopsyAppUIFigure        matlab.ui.Figure
        RobotGuidedBreastBiopsyGUILabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        GettingBiopsyTargetTab         matlab.ui.container.Tab
        PerformPanel                   matlab.ui.container.Panel
        SetTargetLamp                  matlab.ui.control.Lamp
        PlotInsertionLamp              matlab.ui.control.Lamp
        GetTargetLamp                  matlab.ui.control.Lamp
        GetTargetButton                matlab.ui.control.Button
        PlotInsertionButton            matlab.ui.control.Button
        SetTargetButton                matlab.ui.control.Button
        TargetStatePanel               matlab.ui.container.Panel
        DetailsButton                  matlab.ui.control.Button
        BreastHolderCollisionPathLamp  matlab.ui.control.Lamp
        BreastHolderCollisionPathLampLabel  matlab.ui.control.Label
        BreastHolderPanel              matlab.ui.container.Panel
        RotateButton                   matlab.ui.control.Button
        Label_8                        matlab.ui.control.Label
        numLeadTargetLabel             matlab.ui.control.Label
        HolderLeadLabel                matlab.ui.control.Label
        CupSizeDropDown                matlab.ui.control.DropDown
        CupSizeLabel                   matlab.ui.control.Label
        BiopsyTargetPanel              matlab.ui.container.Panel
        zTLabel                        matlab.ui.control.Label
        yTLabel                        matlab.ui.control.Label
        xTLabel                        matlab.ui.control.Label
        ZmmLabel                       matlab.ui.control.Label
        YmmLabel                       matlab.ui.control.Label
        XmmLabel                       matlab.ui.control.Label
        PositionLabel                  matlab.ui.control.Label
        plotInicialization             matlab.ui.control.UIAxes
        PreplacementstageTab           matlab.ui.container.Tab
        HomeViewButton                 matlab.ui.control.Button
        ErrorTablePrePlacement         matlab.ui.control.Table
        TargetPreplacementPanel        matlab.ui.container.Panel
        SimulateButton                 matlab.ui.control.Button
        DoneLamp                       matlab.ui.control.Lamp
        DoneLampLabel                  matlab.ui.control.Label
        TrajectoryStatePanel           matlab.ui.container.Panel
        PlotPathButton                 matlab.ui.control.Button
        PlotTrajectoriesButton         matlab.ui.control.Button
        BiopsyTrajectoryPlannerPanel   matlab.ui.container.Panel
        CalculateTrajectoryLamp        matlab.ui.control.Lamp
        CalculateTrajectoryButton      matlab.ui.control.Button
        SettingsButton                 matlab.ui.control.Button
        SafeNeedlePlacementPanel       matlab.ui.container.Panel
        SimulationSourcePanel          matlab.ui.container.Panel
        ShowEnvLamp                    matlab.ui.control.Lamp
        ShowEnvButton                  matlab.ui.control.Button
        PlotRobotLamp                  matlab.ui.control.Lamp
        ShowModelButton                matlab.ui.control.Button
        DistanceFromSkintoTargetPanel  matlab.ui.container.Panel
        NeedleInsertionEdit            matlab.ui.control.NumericEditField
        mmLabel_4                      matlab.ui.control.Label
        SetBiopsyDeviceToolPanel       matlab.ui.container.Panel
        DeviceDimensionHelpButton      matlab.ui.control.Button
        ThrowmmDropDown                matlab.ui.control.DropDown
        ThrowmmDropDownLabel           matlab.ui.control.Label
        DeadSpaceEditField             matlab.ui.control.NumericEditField
        DeadSpacemmLabel               matlab.ui.control.Label
        SetDeviceLamp                  matlab.ui.control.Lamp
        SetDeviceButton                matlab.ui.control.Button
        GaugeDropDown                  matlab.ui.control.DropDown
        GaugeDropDownLabel             matlab.ui.control.Label
        NeedleLengthEditField          matlab.ui.control.NumericEditField
        NeedleLengthmmLabel            matlab.ui.control.Label
        TypeNeedleDropDown             matlab.ui.control.DropDown
        TypeDropDownLabel              matlab.ui.control.Label
        EndEffectorDistancePanel       matlab.ui.container.Panel
        EEdistanceEdit                 matlab.ui.control.NumericEditField
        mmLabel_5                      matlab.ui.control.Label
        SetSafetyPositionButtonGroup   matlab.ui.container.ButtonGroup
        ReachablePointLamp             matlab.ui.control.Lamp
        ReachablePointLampLabel        matlab.ui.control.Label
        SetPointLamp                   matlab.ui.control.Lamp
        SetPointButton                 matlab.ui.control.Button
        SafetyDistanceEditField        matlab.ui.control.NumericEditField
        SafetyDistanceEditFieldLabel   matlab.ui.control.Label
        Tx_2                           matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        PLabel                         matlab.ui.control.Label
        RLabel                         matlab.ui.control.Label
        TxLabel                        matlab.ui.control.Label
        TzLabel                        matlab.ui.control.Label
        TyLabel                        matlab.ui.control.Label
        Tx                             matlab.ui.control.Label
        OffsetMinusButton              matlab.ui.control.Button
        OffsetPlusButton               matlab.ui.control.Button
        OffsetDropDown                 matlab.ui.control.DropDown
        OffsetDropDownLabel            matlab.ui.control.Label
        ModifyButton                   matlab.ui.control.RadioButton
        DefaultButton                  matlab.ui.control.RadioButton
        plotPrePlacement               matlab.ui.control.UIAxes
        PostplacementstageTab          matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        SimulationControlPanel         matlab.ui.container.Panel
        SuccsessfulBiopsyLamp          matlab.ui.control.Lamp
        SuccsessfulBiopsyLampLabel     matlab.ui.control.Label
        SettingsButton_2               matlab.ui.control.Button
        SimulateStateLamp              matlab.ui.control.Lamp
        GetResultsButton               matlab.ui.control.Button
        StopButtonSIM                  matlab.ui.control.Button
        SimulateButtonSIM              matlab.ui.control.Button
        DataManagementPanel            matlab.ui.container.Panel
        LoadVariablesLamp              matlab.ui.control.Lamp
        saveVariablesLamp              matlab.ui.control.Lamp
        OpenModelLamp                  matlab.ui.control.Lamp
        OpenModelButton                matlab.ui.control.Button
        SimulinkmodelDropDown          matlab.ui.control.DropDown
        SimulinkmodelDropDownLabel     matlab.ui.control.Label
        ReloadVariablesButton          matlab.ui.control.Button
        ReadVariablesButtonSIM         matlab.ui.control.Button
        ResultsPanel                   matlab.ui.container.Panel
        PostplacementLabel             matlab.ui.control.Label
        PreplacementLabel              matlab.ui.control.Label
        TableResultspostRPY            matlab.ui.control.Table
        TableResultspostXYZ            matlab.ui.control.Table
        TableResultspreRPY             matlab.ui.control.Table
        TableResultspreXYZ             matlab.ui.control.Table
    end

    
    properties (Access = public)
        MainPath% main working directory path by getRootDirectory.m
        getInitialBiopsyData
        numTargets
        targetPoints%targets vector
        P_c%Biosy target point
        U%Unit vector in x0y0z0
        V%Perpendicular unit vector
        C_ext%Outer edge of breast holder
        Phi%half angle of cone aperture
        ReachablePoint%Robot Joint limits are not forced
        RotateHolder % angle for rotating the breast holder device in collision type 2
        
        CountTarget % sample index for getting biopsy point 
        
        SizeHolderCup;%cup number of breast holder : A,B,C,D
        NumGridRow;%num of horizontal division in breast holder
        
        MidSegment;
        Alpha90;
        NLimitPlot;
        mechUpperDelta;
        mechLowerDelta;
        Collision1;
        Collision2;
        Collision3
        NumHolderLid;
        NumLeadToCol
        SameSegment;
        DoubleCase;
        inevitableCollision
        inputAngleCase;%To decide about Operations after getting angle value from targetSelection App
        Alpha;
        HolderData
        RobotData
        HolderModel
        TargetData
        
        OffsetPrePlacement;
        H_c;%pre-placement biopsy point
        N_c;%needle insertion direction
        A_c;
        P_s
        H_s
        N_s
        A_s
        E_s
        Tsc
        Pc_s
        OffsetNeedle
        
        safetyDistance;
        safetyDistanceNew;
        NeedleInsertionDistance;
        NeedleLength;
        NeedleDeviceLength;
        NeedleGauge;
        NeedleType;
        rNeedle
        tNeedle
        rInnerNeedle
        NeedleThrow
        NeedleDeadSpace
        
        TrajectoryPlanner
        TrajectoryInterpolation
        NumJoints
        NumPointsJoint

        Q
        Qd
        Qdd
        RobotWayPoints
        WaypointTimes
        TrajTimes
        T0_prePlace
        Tf_prePlace
        TPath
        Twp
        
        RobotModel
        RobotPose
        RobotAxes
        Htraj
        eeRPY
        PrePosition
        ErrorPrePosition
        
        SimulatorType
        mdlSIM
        OutSIM
    end
    
    properties (Access = private)
        %External App names
        targetSelectionApp;
        needleDimensionApp;
    end
    
    methods (Access = private)
                
        function [a_c,n_c,dInsertionPoint] = setInsertionPoint(~,p_c,u,v,alpha,phi,c_ext)
            % Calculating new insertion direction
            screwMatrix= VecToso3(-v);
            Rb = MatrixExp3(screwMatrix,alpha);
            n_new = Rb*u;
            
            % Finding distance from p to new entry a_new
            % Using law of sines
            % From p to b : d1*sin(phi)/sin(betha);
            dInsertionPoint = norm(c_ext-p_c)*sin(phi)/sin(pi-phi-alpha);
            a_new = p_c + n_new*dInsertionPoint;
            
            % Setting final arrangement
            a_c = a_new;%insertion point in breast Holder
            %h_c = setPrePlacementPoint(~,a_c,n_new,offsetPrePlacement)
            n_c = n_new;%needle insertion direction
        end
        
        function alpha = getAlphaInsertion(app,optionInsertion,angle)
            %if angle>0, alpha goes in CCW
            %else angle<0, alpha goes in CW
            if isequal(app.inputAngleCase,1) || isequal(app.inputAngleCase,2)
                if isequal(optionInsertion,1)
                    alpha_old = acos(app.U'*app.NLimitPlot(2,:)');%era 2
                else%optionInsertion == 2
                    alpha_old = acos(app.U'*app.NLimitPlot(3,:)');
                end
                alpha = alpha_old + angle;
            elseif isequal(app.inputAngleCase,3)
                alpha = app.Alpha90 + angle;
            else%case4
                if isequal(app.MidSegment,1) || isequal(app.inevitableCollision,2) 
                    alpha_old = acos(app.U'*app.NLimitPlot(3,:)');
                    alpha = alpha_old + angle;
                else
                    alpha_old = acos(app.U'*app.NLimitPlot(2,:)');
                    alpha = alpha_old + angle;
                end
            end
        end
        
        function h_c = setPrePlacementPoint(~,a_c,n_c,offset)
            h_c = a_c + offset*n_c;%insertion point at a safety distance from holder
        end
        
        function needleLength = setNeedleLength(~,pTarget,pT)
            minDist = norm(pTarget-pT);
            needleDistances = [50,60,80,100,120,150,200];%comertial lengths
            aux = needleDistances(minDist<needleDistances);
            needleLength = aux(1);

            
        end
        
        function robotAxes = plotRobot(app,isRobotHome,oldRobotAxes)
            robotAxes = plotRobotMatlab_App(app.RobotModel,app.RobotPose,isRobotHome,oldRobotAxes);
            
        end
        
        function showBiopsyError(app)
            %Getting data
            dataOut = app.OutSIM;
            outputs = dataOut.get('logsout');
            Rneedle = outputs.get('RotNeedle').Values.Data;
            Rneedle = Rneedle(:,:,end);
            Ree = outputs.get('RotEE').Values.Data;
            Ree = Ree(:,:,end);
            RPYneedle = externalFuntionAPP(Rneedle,'rpy');
            RPYee = externalFuntionAPP(Ree,'rpy');
            pNeedle = outputs.get('pNeedle').Values.Data;
            %at time about  20 seconds, the needle reached the target
            timeSeries = outputs.get('pNeedle').Values.Time;
            aux = find(timeSeries>20.5);
            pNeedle = pNeedle(aux(1),:)'*1000;% in mm
            pT = outputs.get('pT').Values.Data;
            pT = pT(end,:)'*1000;%in mm
            zNeedle = outputs.get('zNeedle').Values.Data;
            zNeedle = zNeedle(:,:,end);
            zEE = outputs.get('zEE').Values.Data;
            zEE = zEE(:,:,end);
            biopsySuccess = outputs.get('biopsySuccess').Values.Data;
            biopsySuccess = biopsySuccess(end,1);
            %Processing
            preErrorAngle = acos(zEE'*app.N_s);
            postErrorAngle = acos(zNeedle'*app.N_s);
            preError = norm(app.H_s - pT);
            postError = norm(app.P_s - pNeedle);
            %Showing
            app.TableResultspreXYZ.Data = [app.H_s' pT' preError];
            app.TableResultspostXYZ.Data = [app.Pc_s' pNeedle' postError]; %Error en tamaño
            app.TableResultspreRPY.Data = [app.eeRPY RPYee preErrorAngle]; 
            app.TableResultspostRPY.Data = [app.eeRPY RPYneedle postErrorAngle];
            if biopsySuccess == 1
                app.SuccsessfulBiopsyLamp.Color = [0,1,0];
            end

        end
    end
    
    methods (Access = public)
        
        function updatePlotInicialization(app,optionInsertion,angle)
            % Function from targetSelection app
            cla(app.plotInicialization)
            hold(app.plotInicialization,'on')
            % Plotting again
            [~,~,~,~,~,~,~,~,~,~,~] = plotBiopsyTarget_App(app.plotInicialization,app.P_c,app.SizeHolderCup,app.U,app.V,app.NumGridRow,app.RobotData,app.HolderData,app.HolderModel,app.RotateHolder);
            app.Alpha = getAlphaInsertion(app,optionInsertion,angle);
            [app.A_c,app.N_c,app.NeedleInsertionDistance] = setInsertionPoint(app,app.P_c,app.U,app.V,app.Alpha,app.Phi,app.C_ext);
            plot3(app.plotInicialization,[app.P_c(1) app.A_c(1)],[app.P_c(2) app.A_c(2)],[app.P_c(3) app.A_c(3)],'Color','m','LineStyle','-.');
            plot3(app.plotInicialization,app.A_c(1),app.A_c(2),app.A_c(3),'md','MarkerSize',6,'MarkerFaceColor','m');
            
            %Showing data label
            app.NeedleInsertionEdit.Value = app.NeedleInsertionDistance;         
            
        end
        
        function [minAngle,maxAngle] = sendInsertionSideOption(app,optionInsertion)
            % Function from targetSelection app
            if app.inputAngleCase==1 || app.inputAngleCase==2
                % CW(-) and CCW(+)
                if isequal(optionInsertion,1)%superior insertion side
                    maxAngle = 0;
                    minAngle = -app.mechUpperDelta;
                else
                    maxAngle = app.mechLowerDelta;
                    minAngle = 0;
                end
            else
                %do nothing
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupApp(app)
            app.CountTarget = 1;%Go from 1 to sample biopsy target number 
            
            app.Collision1 = false;
            app.Collision2 = false;
            app.Collision3 = false;
            
            app.getInitialBiopsyData = false;
            app.numTargets = 30;
            app.targetPoints = zeros(3,app.numTargets);
            app.RotateHolder = 0;% No initial rotation for breast holder device
            
            app.SizeHolderCup = 'A';
            app.ReachablePoint = true;
            app.NumGridRow = 3;
            app.OffsetPrePlacement = 10;%mm
            app.plotInicialization.Visible = 'off';
            app.safetyDistance = 10.0;
            app.safetyDistanceNew = app.safetyDistance;
            app.SafetyDistanceEditField.Value = app.safetyDistance;
            app.plotPrePlacement.Visible = 'off';
            
            app.SafetyDistanceEditField.Enable = 'off';
            app.OffsetDropDown.Enable = 'off';
            app.OffsetMinusButton.Enable = 'off';
            app.OffsetPlusButton.Enable = 'off';
            
            app.NeedleThrow = 0;%20 or 10 mm
            app.NeedleType = 'needleFNA';
            app.NeedleGauge = 20;
            app.tNeedle = 0.152;
            app.NeedleLength = 100;
            app.GaugeDropDown.Items = {'20','21'};%FNA needle gauge by default
            app.ThrowmmDropDown.Visible = 'off';
            app.ThrowmmDropDownLabel.Visible = 'off';
            app.DeadSpaceEditField.Visible = 'off';
            app.DeadSpacemmLabel.Visible = 'off';
            app.NeedleDeadSpace = 0;%8mm
            app.OffsetNeedle = 5.47;
            
            app.TrajectoryInterpolation = 1; % trapezoidal
            app.TrajectoryPlanner = 1; % JointTrajectory
            app.T0_prePlace = 1;
            app.Tf_prePlace = 4;
            app.NumPointsJoint = 10;
            
            % Modeling Data
            app.MainPath = getRootDirectory;%Get main working folder
            app.TargetData = load(strcat(app.MainPath,'/Simulink_models/Data/biopsyData.mat'));
            pathRobotModel = strcat(app.MainPath,'\Simulink_models\Data\robotModels.mat');
            app.RobotData =  load(strcat(app.MainPath,'\simulink_models\Data\robotData.mat'));
            app.HolderModel = load(strcat(app.MainPath,'\Simulink_models\Data\breastHolderModel.mat'));
            % Particular default variables
            app.Tsc = app.RobotData.Tsc;
            app.eeRPY = [0 0 0];
            app.P_c = [0 0 0]';
            app.H_s = app.P_c;
            app.H_c = app.P_c;
            app.P_s = app.P_c;
            app.Pc_s = app.P_c;
            app.N_c = app.P_c;
            app.N_s = app.P_c;
            
            [app.RobotModel,app.NumJoints,app.RobotPose] = loadingRobot_App(pathRobotModel);%loadingRobot
            
            app.SimulatorType = 'Matlab';
            app.mdlSIM = 'breastBiopsyApp';
            
        end

        % Button pushed function: GetTargetButton
        function GetTargetButtonPushed(app, event)
            app.GetTargetLamp.Color = [1,1,0];
            try
                %-------------------------------------%
                % Biopsy target sample number
%                 nSample = 10;
%                 
%                 %Getting a randomly Biopsy target by Latin Hypercube Sampling (LHS)
%                 rng(2022)
%                 s = lhsdesign(nSample,1,'Smooth','on');
%                 app.SizeHolderCup = app.CupSizeDropDown.Value;
%                 
%                 if ~app.getInitialBiopsyData
%                     if strcmp(app.SizeHolderCup,'A')
%                         biopsyPoint = app.TargetData.targetDataA;
%                     elseif strcmp(app.SizeHolderCup,'B')
%                         biopsyPoint = app.TargetData.targetDataB;
%                     elseif strcmp(app.SizeHolderCup,'C')
%                         biopsyPoint = app.TargetData.targetDataC;
%                     else
%                         biopsyPoint = app.TargetData.targetDataD;
%                     end
%                     % if biopsyPoint =  numBiopsyPoints x 3
%                     
%                     % Total number of available target points
%                     numBiopsyPoints = length(biopsyPoint);
%                     
%                     % Getting random target sample
%                     app.targetPoints = biopsyPoint(round(1 + (numBiopsyPoints-1)*s),:);%1 for first row index
%                     app.getInitialBiopsyData=true;
%                     
%                     app.getInitialBiopsyData = true;
%                 end
                
                % Selecting the current target point
                %app.P_c = app.targetPoints(app.CountTarget,:)';%3x1
                %-------------------------------------%
                
                % A fixed target position %% Uncomment only this line and
                % comment the other two blocks
                app.P_c = [-30.1200, -34.600, 8.0000]';
                
                %-------------------------------------%
                % Reinitializating the randon target
%                 if app.CountTarget > nSample-1
%                     app.CountTarget = 1;
%                 else
%                     app.CountTarget =  app.CountTarget + 1;
%                 end
                %-------------------------------------%

                % Getting Breast Holder dimensions
                if strcmp(app.SizeHolderCup,'A')
                    fileCup = "\Simulink_models\Data\dataCupA.mat";
                elseif  strcmp(app.SizeHolderCup,'B')
                    fileCup = "\Simulink_models\Data\dataCupB.mat";
                elseif  strcmp(app.SizeHolderCup,'C')
                    fileCup = "\Simulink_models\Data\dataCupC.mat";
                else
                    fileCup = "\Simulink_models\Data\dataCupD.mat";
                end
                app.HolderData = load(strcat(app.MainPath,fileCup));
                
                %% Functions
                [app.U,app.V,app.C_ext,app.Phi] = setInsertionPlot_App(app.P_c,app.HolderData);
  
                %Sending data
                app.xTLabel.Text = string(app.P_c(1));
                app.yTLabel.Text = string(app.P_c(2));
                app.zTLabel.Text = string(app.P_c(3));
                
                %Succesful action
                app.GetTargetLamp.Color = [0,1,0];
                
                %Reseting colors to yellow again
                app.PlotInsertionLamp.Color = [1,1,0];
                app.SetTargetLamp.Color = [1,1,0];
                app.SetPointLamp.Color = [1,1,0];
                app.CalculateTrajectoryLamp.Color = [1,1,0];
                app.DoneLamp.Color = [1,1,0];
                
                app.Pc_s = app.Tsc*[app.P_c; 1];
                app.Pc_s = app.Pc_s(1:3)';
            catch ME
                disp(ME.message);
                app.GetTargetLamp.Color = [1,0,0];
            end
            
            
        end

        % Button pushed function: PlotInsertionButton
        function PlotInsertionButtonPushed(app, event)
            app.SetTargetLamp.Color = [1,1,0];
            try
                if sum(app.P_c)~=0
                    %Reset breast holder rotation angle for a new plot
                    app.RotateHolder = 0;
                    
                    app.plotInicialization.Visible = 'on';
                    cla(app.plotInicialization);
                    hold(app.plotInicialization,'on');
                    [app.MidSegment,app.Alpha90,app.NLimitPlot,app.mechUpperDelta,app.mechLowerDelta,app.Collision1,...
                    app.Collision2,app.Collision3,app.NumHolderLid,app.DoubleCase,app.inevitableCollision] = ...
                    plotBiopsyTarget_App(app.plotInicialization,app.P_c,app.SizeHolderCup,app.U,app.V,app.NumGridRow,app.RobotData,app.HolderData,app.HolderModel,app.RotateHolder);
                    
                    app.numLeadTargetLabel.Text = string(app.NumHolderLid);
                    
                    % Showing Breast holder device collisions
                    if app.Collision2
                        app.BreastHolderCollisionPathLamp.Color = [1,0.5,0];%red: warning
                    else
                        app.BreastHolderCollisionPathLamp.Color = [0,1,0];%green: valided target
                    end
                    
                    app.PlotInsertionLamp.Color = [0,1,0];
                end
            catch ME
                disp(ME.message);
                app.PlotInsertionLamp.Color = [1,0,0];%red: error
            end
            
        end

        % Button pushed function: SetTargetButton
        function SetTargetButtonPushed(app, event)
            %Calling an auxiliar APP to select biopsy target and needle insertion 
            % The angle sign is based on CW (-) and CCW (+)
            app.SetTargetLamp.Color = [1,1,0];
            enableOptionMultiple = false;
            try
                app.SetTargetButton.Enable = "off";
                if ~app.Collision1 && app.DoubleCase
                    enableOptionMultiple = true;
                    maxAngle = 0;
                    minAngle = -app.mechUpperDelta;
                    app.inputAngleCase = 1;
                elseif app.Collision1 && app.DoubleCase% isequal(app.inevitableCollision,0)
                    enableOptionMultiple = true;
                    maxAngle = 0;
                    minAngle = -app.mechUpperDelta;
                    app.inputAngleCase = 2;
                elseif ~app.Collision1 && ~app.DoubleCase%app.SameSegment
                    maxAngle = app.mechLowerDelta;
                    minAngle = -app.mechUpperDelta;
                    app.inputAngleCase = 3;
                else% app.Collision1 && ~app.DoubleCase
                    if  ~isequal(app.inevitableCollision,1)
                        if isequal(app.MidSegment,1) || isequal(app.inevitableCollision,2)
                            maxAngle = app.mechLowerDelta;%app.mechUpperDelta;
                            minAngle = 0;
                        else %isequal(app.MidSegment,7) || isequal(app.inevitableCollision,3)
                            maxAngle = 0;
                            minAngle = -app.mechUpperDelta;%mechLowerDelta
                        end
                        app.inputAngleCase = 4;           
                    elseif isequal(app.inevitableCollision,1)
                        % Target can't be reached by collision. Point near to the
                        % internal side of breast holder
                        app.inputAngleCase = 5;
                    end
                end

                if app.inputAngleCase ~= 5
                    app.targetSelectionApp = targetSelection(app,minAngle,maxAngle,enableOptionMultiple);
                    app.SetTargetLamp.Color = [0,1,0];
                else
                    % Showing a error message
                    m1 = "Target can't be reached by an unavoidable collision. Case type 5\n Point near to the " + ...
                        "internal side of breast holder.";
                    message = sprintf(m1);
                    uialert(app.BreastBiopsyAppUIFigure,message,'Case type 5');
                    app.SetTargetButton.Enable = "on";
                    app.SetTargetLamp.Color = [1,0.41,0.16];
                end
            catch ME
                disp(ME.message);
                app.SetTargetLamp.Color = [1,0,0];
            end
        end

        % Close request function: BreastBiopsyAppUIFigure
        function BreastBiopsyAppUIFigureCloseRequest(app, event)
            close all;
            delete(app.targetSelectionApp)
            delete(app.needleDimensionApp);
            delete(app)
        end

        % Selection changed function: SetSafetyPositionButtonGroup
        function SetSafetyPositionButtonGroupSelectionChanged(app, event)
            %selectedButton = app.SetSafetyPositionButtonGroup.SelectedObject;
            if app.ModifyButton.Value
                app.SafetyDistanceEditField.Enable = 'on';
                app.OffsetDropDown.Enable = 'on';
                app.OffsetMinusButton.Enable = 'on';
                app.OffsetPlusButton.Enable = 'on';
            else
                app.SafetyDistanceEditField.Value = app.safetyDistance;
                app.SafetyDistanceEditField.Enable = 'off';
                app.OffsetDropDown.Enable = 'off';
                app.OffsetMinusButton.Enable = 'off';
                app.OffsetPlusButton.Enable = 'off';
            end
        end

        % Button pushed function: OffsetPlusButton
        function OffsetPlusButtonPushed(app, event)
            app.safetyDistanceNew = app.SafetyDistanceEditField.Value;
            offsetNum = app.OffsetDropDown.Value;
            switch offsetNum
                case "0.5mm"
                    offset = 0.5;
                case "1mm"
                    offset = 1;
                case "1.5mm"
                    offset = 1.5;
                case "2mm"
                    offset = 2;
                case "5mm"
                    offset = 5;
            end
            app.safetyDistanceNew = app.safetyDistanceNew + offset;
            app.SafetyDistanceEditField.Value = app.safetyDistanceNew;
            app.SetPointLamp.Color = [1,1,0];%yellow
            app.CalculateTrajectoryLamp.Color = [1,1,0];
            app.DoneLamp.Color = [1,1,0];
        end

        % Button pushed function: OffsetMinusButton
        function OffsetMinusButtonPushed(app, event)
            app.safetyDistanceNew = app.SafetyDistanceEditField.Value;
            offsetNum = app.OffsetDropDown.Value;
            switch offsetNum
                case "0.5mm"
                    offset = 0.5;
                case "1mm"
                    offset = 1;
                case "1.5mm"
                    offset = 1.5;
                case "2mm"
                    offset = 2;
                case "5mm"
                    offset = 5;
            end
            app.safetyDistanceNew = app.safetyDistanceNew - offset;
            app.SafetyDistanceEditField.Value = app.safetyDistanceNew;
            app.SetPointLamp.Color = [1,1,0];%yellow
            app.CalculateTrajectoryLamp.Color = [1,1,0];
            app.DoneLamp.Color = [1,1,0];
        end

        % Button pushed function: SetPointButton
        function SetPointButtonPushed(app, event)
            app.SetPointLamp.Color = [1,1,0];%yellow
            try
                %Saving final placement distance
                app.safetyDistance = app.safetyDistanceNew;
                %Calculating pre-placement point
                app.H_c = setPrePlacementPoint(app,app.A_c,app.N_c,app.safetyDistance);
                %Ploting
                app.plotPrePlacement.Visible = 'on';
                cla(app.plotPrePlacement);
                hold(app.plotPrePlacement,'on');
                [~,~,~,~,~,~,~,~,~,~,~] = plotBiopsyTarget_App(app.plotPrePlacement,app.P_c,app.SizeHolderCup,app.U,app.V,app.NumGridRow,app.RobotData,app.HolderData,app.HolderModel,app.RotateHolder);
                plot3(app.plotPrePlacement,[app.P_c(1) app.H_c(1)],[app.P_c(2) app.H_c(2)],[app.P_c(3) app.H_c(3)],'Color','r','LineStyle','-.');
                plot3(app.plotPrePlacement,app.H_c(1),app.H_c(2),app.H_c(3),'md','MarkerSize',10,'MarkerFaceColor','g');
                etiqueta = '\leftarrow hc';
                text(app.plotPrePlacement,app.H_c(1),app.H_c(2),app.H_c(3),etiqueta,"FontSize",14,"Color",'r');
                
                 % In CS {0} or robot base frame
                [app.P_s,app.A_s,app.N_s,app.H_s,app.eeRPY] = transformToRobotBase_App(app.P_c,app.A_c,app.N_c,app.H_c,app.RobotData);
                %Evaluating reachability of target point by inverse
                %kinematic of joint limits
                app.ReachablePoint = isExceededJointLimits_App(app.H_s,app.N_s,app.RobotData);
                
                %Showing data
                if app.ReachablePoint == true
                    app.ReachablePointLamp.Color = [0,1,0];%green
                else
                    app.ReachablePointLamp.Color = [1,0,0];%red
                end
                app.NeedleLength = setNeedleLength(app,app.P_s,app.H_s);
                
                app.NeedleLengthEditField.Value = app.NeedleLength;
                app.TxLabel.Text = string(app.H_s(1));
                app.TyLabel.Text = string(app.H_s(2));
                app.TzLabel.Text = string(app.H_s(3));
                app.RLabel.Text = string(app.eeRPY(1));
                app.PLabel.Text = string(app.eeRPY(2));
                app.YLabel.Text = string(app.eeRPY(3));
                app.SetPointLamp.Color = [0,1,0];%green
                
            catch ME
                disp(ME.message);
                app.SetPointLamp.Color = [1,0,0];%red
            end
            
        end

        % Value changed function: TypeNeedleDropDown
        function TypeNeedleDropDownValueChanged(app, event)
            app.NeedleType = app.TypeNeedleDropDown.Value;
            if strcmp(app.NeedleType,'needleFNA')
                listGauge = {'20','21'};
                app.ThrowmmDropDown.Visible = 'off';
                app.ThrowmmDropDownLabel.Visible = 'off';
                app.DeadSpaceEditField.Visible = 'off';
                app.DeadSpacemmLabel.Visible = 'off';
                app.NeedleThrow = 0;%default
                app.NeedleDeadSpace = 0;%default
            elseif strcmp(app.NeedleType,'needleCNB1')
                listGauge = {'11','14','16','18'};
                app.ThrowmmDropDown.Visible = 'on';
                app.ThrowmmDropDownLabel.Visible = 'on';
                app.DeadSpaceEditField.Visible = 'on';
                app.DeadSpacemmLabel.Visible = 'on';
                app.NeedleThrow = 10;
                app.NeedleDeadSpace = 8;%fixed
            else%needleCNB2
                listGauge = {'11','14','16','18'};
                app.ThrowmmDropDown.Visible = 'on';
                app.ThrowmmDropDownLabel.Visible = 'on';
                app.DeadSpaceEditField.Visible = 'on';
                app.DeadSpacemmLabel.Visible = 'on';
                app.NeedleThrow = 10;
                app.NeedleDeadSpace = 8;%fixed
            end
            app.GaugeDropDown.Items = listGauge;
            app.SetDeviceLamp.Color = [1,1,0];%yellow
        end

        % Button pushed function: SettingsButton, SettingsButton_2
        function SettingsButtonPushed(app, event)
            %Modifying trajectory planner settings
            fig = uifigure('Name','Trajectory Planner Settings');
            
            % Labels
            label1 = uilabel(fig);
            label1.Position = [50 280 200 20];
            label1.Text = "Trajectory Interpolation";
            label2 = uilabel(fig);
            label2.Position = [50 250 200 20];
            label2.Text = "# Points path by joint segment";
            label2.WordWrap = "on";
            label3 = uilabel(fig);
            label3.Position = [50 220 200 20];
            label3.Text = "Trajectory type";
            label3.WordWrap = "on";
            %label4 = uilabel(fig);
            %label4.Position = [100 450 120 20];
            %label4.Text = "# Points path 3";
            label5 = uilabel(fig);
            label5.Position = [50 360 400 20];
            label5.Text = "Initial pre-placement time T0";
            label5.WordWrap = "on";
            label6 = uilabel(fig);
            label6.Position = [50 320 400 20];
            label6.Text = "Final pre-placement time Tf";
            label6.WordWrap = "on";
            
            TinitialSlider = uislider(fig,'Position',[300 375 120 3]);
            TinitialSlider.Limits = [0 3];
            TinitialSlider.Value = app.T0_prePlace;%by default 10 seconds
            TfinalSlider = uislider(fig,'Position',[300 335 200 3]);
            TfinalSlider.Limits = [3 5];
            TfinalSlider.Value = app.Tf_prePlace;%by default 10 seconds
            
            % Parameter 1
            TrajectoryInterpolationDD = uidropdown(fig,'Items',{'Trapezoidal','Cubic','Quintic','Bsplane'},'Value','Cubic',...
                              'Position',[300 280 120 20]);
            % Parameter 2
            NumWayPoints = uidropdown(fig,'Items',["21","31","41","51","61"],'Value',"21",...
                              'Position',[320 250 50 20]);
            NumWayPoints.ItemsData = [21 31 41 51 61];
            %NumPointsperJoint = uieditfield(fig,'numeric','Position',[320 250 50 20],'Value',app.NumPointsJoint);
            %NumPointsperJoint.Limits = [20 50];
            
            % Parameter 3
            TrajectoryPlannerDD = uidropdown(fig,'Items',{'Joint Trajectory','Transform Trajectory','Other'},'Value','Joint Trajectory',...
                              'Position',[300 220 120 20]);
            
            % Set parameters button
            setButton = uibutton(fig,'push','Text', 'Set Settings','Position',[300, 50, 200, 22],...
                'ButtonPushedFcn', @(setButton,event) setSettings(app,fig,TinitialSlider,TfinalSlider,...
               TrajectoryInterpolationDD,NumWayPoints,TrajectoryPlannerDD));
                        
            function setSettings(app,fig,TinitialSlider,TfinalSlider,TrajectoryInterpolationDD,NumWayPoints,TrajectoryPlannerDD)
                if strcmp(TrajectoryInterpolationDD.Value,"Trapezoidal")
                    app.TrajectoryInterpolation = 1;
                elseif strcmp(TrajectoryInterpolationDD.Value,"Cubic")
                    app.TrajectoryInterpolation = 2;
                elseif strcmp(TrajectoryInterpolationDD.Value,"Quintic")
                    app.TrajectoryInterpolation = 3;
                else
                    app.TrajectoryInterpolation = 4;%bsplane
                end
                
                if strcmp(TrajectoryPlannerDD.Value,"Joint Trajectory")
                    app.TrajectoryPlanner = 1;
                else% strcmp(TrajectoryPlannerDD.Value,"Transform Trajectory")
                    app.TrajectoryPlanner = 2;
                end
                app.T0_prePlace = TinitialSlider.Value;
                app.Tf_prePlace = TfinalSlider.Value;
                app.NumPointsJoint = double(NumWayPoints.Value);
                disp(app.NumPointsJoint)                
                uialert(fig,'Message','Parameters modified','Icon','success');
                
                %Show that we need to recaculate trajectory
                app.CalculateTrajectoryLamp.Color = [1,1,0];
            end
            
        end

        % Button pushed function: CalculateTrajectoryButton
        function CalculateTrajectoryButtonPushed(app, event)
            try
                if app.TrajectoryPlanner == 1 %JointTraj
                    [app.Q,app.dQ,app.ddQ,app.RobotWayPoints,app.TrajTimes,app.WaypointTimes,~,~] = ...
                    getJointTrajectoryPlanner_App(app.TrajectoryInterpolation,app.H_s,app.N_s,app.T0_prePlace,app.Tf_prePlace,app.NumPointsJoint,app.NumJoints,app.RobotData);
                
                else %app.TrajectoryPlanner == 2% TransTraj
                    [app.TPath,app.WaypointTimes,app.Twp] = ...
                        getTransTrajectoryPlannerApp(app.H_s,app.N_s,app.T0_prePlace,app.Tf_prePlace,app.NnumPoints,app.RobotData);
                end
                app.CalculateTrajectoryLamp.Color = [0,1,0];
            catch ME
                app.CalculateTrajectoryLamp.Color = [1,0,0];
                disp(ME.message);
                warning('Error: Trajectory was not calculated');
            end
    
        end

        % Button pushed function: ShowModelButton
        function ShowModelButtonPushed(app, event)
            app.PlotRobotLamp.Color = [1,1,0];
            try
                    isRobotHome = true;
                    app.RobotAxes = plotRobot(app,isRobotHome,0);
                    app.PlotRobotLamp.Color = [0,1,0];
                catch ME
                    disp(ME.message);
                    app.PlotRobotLamp.Color = [1,0,0];
            end
        end

        % Button pushed function: PlotPathButton
        function PlotPathButtonPushed(app, event)
            try
                app.Htraj = plotPath_App(app.RobotWayPoints,1,app.RobotAxes);
            catch ME
                disp(ME.message);
                disp("Show robot model first !!!");
            end
           
        end

        % Button pushed function: PlotTrajectoriesButton
        function PlotTrajectoriesButtonPushed(app, event)
            plotTrajectoryCurves(app.TrajTimes,app.Q,app.dQ,app.ddQ,app.NumJoints,app.WaypointTimes);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            try
                if app.TrajectoryPlanner==1
                    nameEE = 'link5';%In pre-placement
                    app.PrePosition = plotJointTrajectoryApp(app.RobotModel,app.Q,app.TrajTimes,1,nameEE,app.RobotAxes,app.Htraj);
                    app.PrePosition = app.PrePosition*1000;%in mm
                    
                    %Ploting the final position of end-effector robot
                    hold(app.plotPrePlacement,'on')
                    plot3(app.plotPrePlacement,app.PrePosition(1),app.PrePosition(2),app.PrePosition(3),'Color',[0,0.4,1],'Marker','o');
                else
                    % Transform trajectories
                    app.PrePosition = plotTransTrajectoryApp(app.RobotModel,app.TPath,app.WaypointTimes,1,app.RobotAxes,app.Htraj);
                    app.PrePosition = app.PrePosition*1000;

                    %Ploting the final position of end-effector robot
                    hold(app.plotPrePlacement,'on')
                    plot3(app.plotPrePlacement,app.PrePosition(1),app.PrePosition(2),app.PrePosition(3),'Color',[0,0.4,1],'Marker','o');
                end
                %Showing Data
                app.ErrorPrePosition = norm(app.PrePosition'-app.H_s);%in mm
                app.ErrorPrePosition = round(app.ErrorPrePosition,2);
                errorTable = [app.PrePosition(1) app.PrePosition(2) app.PrePosition(3) app.ErrorPrePosition];
                app.ErrorTablePrePlacement.Data = errorTable;
                app.DoneLamp.Color = [0,1,0];%green
            catch ME
                disp(ME.message);
                app.DoneLamp.Color = [1,0,0];
            end
            
        end

        % Button pushed function: SetDeviceButton
        function SetDeviceButtonPushed(app, event)
            app.NeedleGauge = str2double(app.GaugeDropDown.Value);
            switch app.NeedleGauge
                case 11
                    app.rNeedle = 3.048/2;%Radius in mm
                    app.rInnerNeedle = 2.388/2;% minus 2 gauge dimension
                    app.tNeedle = 0.33;%Thickness in mm
                case 14
                    app.rNeedle = 2.109/2;
                    app.rInnerNeedle = 1.6/2; 
                    app.tNeedle = 0.254;
                case 16
                    app.rNeedle = 1.651/2;
                    app.rInnerNeedle = 1.194/2;
                    app.tNeedle = 0.229;
                case 18
                    app.rNeedle = 1.27/2;
                    app.rInnerNeedle = 0.838/2;
                    app.tNeedle = 0.216;
                case 20
                    app.rNeedle = 0.908/2;
                    app.rInnerNeedle = 0.603/2;
                    app.tNeedle = 0.152;
                case 21
                    app.rNeedle = 0.819/2;
                    app.rInnerNeedle = 0.514/2;
                    app.tNeedle = 0.152;
                otherwise
                    app.rNeedle = 1.27/2;
                    app.rInnerNeedle = 0.838/2;
                    app.tNeedle = 0.216;
            end

            %Updating type 3collision  
            Rholder = app.HolderData.R_upper;%major radius of breast holder
            [app.Collision3,app.NeedleDeviceLength,sepDist]= collisionDevice_App(app.P_c,app.N_c,app.V,app.NeedleInsertionDistance,app.NeedleGauge,app.NeedleType,app.NeedleThrow,app.NeedleDeadSpace,Rholder);
            if ~app.Collision3
                loadingBiopsyDevice_App(app.RobotModel,app.NeedleType,app.rNeedle,app.NeedleLength);
                addingEndEffector_App(app.RobotModel,2,app.NeedleLength);
                cla(app.plotPrePlacement);
                if strcmp(app.NeedleType,'needleCNB1') || strcmp(app.NeedleType,'needleCNB2')
                    %Plotting again previous plot
                    [~,~,~,~,~,~,~,~,~,~,~] = plotBiopsyTarget_App(app.plotPrePlacement,app.P_c,app.SizeHolderCup,app.U,app.V,app.NumGridRow,app.RobotData,app.HolderData,app.HolderModel,app.RotateHolder);
                    %Plotting safty pre-placement location
                    plot3(app.plotPrePlacement,[app.P_c(1) app.H_c(1)],[app.P_c(2) app.H_c(2)],[app.P_c(3) app.H_c(3)],'Color','r','LineStyle','-.');
                    plot3(app.plotPrePlacement,app.H_c(1),app.H_c(2),app.H_c(3),'md','MarkerSize',10,'MarkerFaceColor','g');
                    etiqueta = '\leftarrow hc';
                    text(app.plotPrePlacement,app.H_c(1),app.H_c(2),app.H_c(3),etiqueta,"FontSize",14,"Color",'r');                               
                    %Plotting possible collision with needle
                    pTip = app.P_c - app.N_c*(app.NeedleThrow + app.NeedleDeadSpace);
                    pc_Tip = [app.P_c';pTip'];
                    line(app.plotPrePlacement,pc_Tip(:,1),pc_Tip(:,2),pc_Tip(:,3),'Color','b','LineStyle',':')
                    plot3(app.plotPrePlacement,pTip(1),pTip(2),pTip(3),'b>','MarkerSize',5,'MarkerFaceColor','c');
                else
                    [~,~,~,~,~,~,~,~,~,~,~] = plotBiopsyTarget_App(app.plotPrePlacement,app.P_c,app.SizeHolderCup,app.U,app.V,app.NumGridRow,app.RobotData,app.HolderData,app.HolderModel,app.RotateHolder);
                    %Plotting safty pre-placement location
                    plot3(app.plotPrePlacement,[app.P_c(1) app.H_c(1)],[app.P_c(2) app.H_c(2)],[app.P_c(3) app.H_c(3)],'Color','r','LineStyle','-.');
                    plot3(app.plotPrePlacement,app.H_c(1),app.H_c(2),app.H_c(3),'md','MarkerSize',10,'MarkerFaceColor','g');
                    etiqueta = '\leftarrow hc';
                    text(app.plotPrePlacement,app.H_c(1),app.H_c(2),app.H_c(3),etiqueta,"FontSize",14,"Color",'r');
                end
                %Computing minimum needle insertion distance in q5
                EEsep = app.NeedleLength + app.NeedleThrow/2 - (norm(app.P_s-app.H_s) + app.OffsetNeedle);
                app.EEdistanceEdit.Value = round(EEsep,2);
                
                message = sprintf("The needle insertion is safe. No collision with cheast wall detected.\n"+"Separation distance is "+round(sepDist*1000,2)+" mm.");
                uialert(app.BreastBiopsyAppUIFigure,message,'Device selected','Icon','success');
                app.SetDeviceLamp.Color = [0,1,0];%green
            else
                %there's a problem
                message = sprintf("Type 3 collision is detected::\nPosible damage with patient's chest Wall !!!");
                uialert(app.BreastBiopsyAppUIFigure,message,'Device selected','Icon','warning');
                app.SetDeviceLamp.Color = [1,0,0];%red
            end
            
        end

        % Value changed function: GaugeDropDown
        function GaugeDropDownValueChanged(app, event)
            app.SetDeviceLamp.Color = [1,1,0];%yellow
        end

        % Button pushed function: ReadVariablesButtonSIM
        function SaveVariablesButtonSIMPushed(app, event)
            app.saveVariablesLamp.Color  = [1,1,0];
            app.LoadVariablesLamp.Color = [1,1,0];
            
            try
                %Saving simulation data
                % Data in mm
                needleLength = app.NeedleLength;
                innerNeedleLength = app.NeedleThrow + app.NeedleDeadSpace;
                needleInsertionDistance = app.NeedleInsertionDistance;
                ps = app.P_s;
                pc = app.Pc_s;
                hs = app.H_s;
                ns = app.N_s;
                reachablePoint = app.ReachablePoint;
                numHolderLid = app.NumHolderLid;
                sizeHolderCup = app.SizeHolderCup;
                needleGauge = app.NeedleGauge;
                if strcmp(app.NeedleType,'needleFNA')
                    needleType = 1;
                elseif strcmp(app.NeedleType,'needleCNB1')
                    needleType = 2;
                else
                    needleType = 3;%needleCNB2
                end
                typeTrajInterp = app.TrajectoryInterpolation;%1,2,3,4
                typeTrajMode = app.TrajectoryPlanner;%1,2
                tfPre = app.Tf_prePlace;
                t0Pre = app.T0_prePlace;
                numPointsJoint = app.NumPointsJoint;
                Rneedle = app.rNeedle;
                RinnerCannula = app.rInnerNeedle;
                Tneedle = app.tNeedle;
                needleThrow = app.NeedleThrow;
                needleDeadSpace = app.NeedleDeadSpace;
                needleDeviceLength = app.NeedleDeviceLength;
                % For breast model profile
                RsupHolder = app.HolderData.R_upper;
                RinfHolder = app.HolderData.R_lower;
                Hholder = app.HolderData.H_holder;
                
                pathToSave = strcat(app.MainPath,'\Simulink_models\Data\AppData.mat'); 
                save(pathToSave, 'needleLength', 'ps', 'pc', 'hs', 'ns', 'reachablePoint' , 'numHolderLid', 'sizeHolderCup', 'needleInsertionDistance', ...
                    'needleGauge', 'Rneedle', 'Tneedle', 'typeTrajInterp', 'typeTrajMode', 'needleType', 't0Pre', 'tfPre', 'RsupHolder', 'RinfHolder', 'Hholder', ...
                    'needleThrow', 'needleDeadSpace', 'RinnerCannula', 'innerNeedleLength', 'needleDeviceLength',  'numPointsJoint');

                app.saveVariablesLamp.Color  = [0,1,0];
            catch ME
                disp(ME.message)
                warning('Error: Data was not saved');
                app.saveVariablesLamp.Color  = [1,0,0];
            end
        end

        % Button pushed function: ReloadVariablesButton
        function ReloadVariablesButtonPushed(app, event)
            app.LoadVariablesLamp.Color = [1,1,0];
            try
                % Updating simulink model workspace 
                if ~bdIsLoaded(app.mdlSIM)
                    open_system(app.mdlSIM);
                end
                loadingData_App; %Load variables data for simulink
                mdlWorkSpaceSIM = get_param(app.mdlSIM,'modelworkspace');
                mdlWorkSpaceSIM.reload;
                app.LoadVariablesLamp.Color = [0,1,0];
            catch ME
                disp(ME.message);
                warning('Error: Data was not reloaded');
                app.LoadVariablesLamp.Color = [1,0,0];
            end 
            
        end

        % Button pushed function: SimulateButtonSIM
        function SimulateButtonSIMPushed(app, event)
            app.SimulateStateLamp.Color = [1,1,0];
            try
                in = Simulink.SimulationInput(app.mdlSIM);
                app.OutSIM = sim(in);
                assignin('base','outSIM',app.OutSIM);
                app.SimulateStateLamp.Color = [0,1,0];
            catch ME
                disp(ME.message)
                warning('Error: Simulation failed');
                app.SimulateStateLamp.Color = [1,0,0];
            end
        end

        % Button pushed function: StopButtonSIM
        function StopButtonSIMPushed(app, event)
            set_param(app.mdlSIM,'SimulationCommand','Stop');
            app.LoadVariablesLamp.Color = [1,0,0];
        end

        % Button pushed function: OpenModelButton
        function OpenModelButtonPushed(app, event)
            modelName = app.SimulinkmodelDropDown.Value;
            app.OpenModelLamp.Color = [1,1,0];
            try
                %For opening other models
                if strcmp(modelName,'Model1')
                    app.mdlSIM = 'breastBiopsyApp';
                end
                
                if ~bdIsLoaded(app.mdlSIM)
                    open_system(app.mdlSIM)
                end
                app.OpenModelLamp.Color = [0,1,0];
            catch ME
                disp(ME.message)
                warning('Error: Model failed to open');
                app.OpenModelLamp.Color = [1,0,0];
            end
        end

        % Button pushed function: GetResultsButton
        function GetResultsButtonPushed(app, event)
            %Simulink.SimulationOutput for post-processing
            showBiopsyError(app);
        end

        % Button pushed function: HomeViewButton
        function HomeViewButtonPushed(app, event)
            %view(app.plotPrePlacement,app.V);
        end

        % Button pushed function: DetailsButton
        function DetailsButtonPushed(app, event)
            col1 = string(double(app.Collision1));
            col2 = string(double(app.Collision2));
            col3 = string(double(app.Collision3));
            m1 = "Type 1 collision - Breast Holder Row : "+col1+'\n';
            m2 = "Type 2 collision - Breast Holder Column : "+col2+'\n';
            m3 = "Type 3 collision - Chest Wall : "+col3+'\n';
            message = sprintf(m1 + m2 + m3);
            uialert(app.BreastBiopsyAppUIFigure,message,'Collision Detail','Icon','info');
        end

        % Button pushed function: DeviceDimensionHelpButton
        function DeviceDimensionHelpButtonPushed(app, event)
            if strcmp(app.NeedleType,'needleFNA')
                app.needleDimensionApp = needleFineDimension;
            else
                app.needleDimensionApp = needleCoreDimension;
            end
        end

        % Value changed function: ThrowmmDropDown
        function ThrowmmDropDownValueChanged(app, event)
            value = app.ThrowmmDropDown.Value;
            app.NeedleThrow = str2double(value);
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            app.SetTargetLamp.Color = [1,1,0];
            try
                %Rotation for breast holder device ploting
                app.RotateHolder = 10;%sexagesimal
                
                cla(app.plotInicialization);
                [app.MidSegment,app.Alpha90,app.NLimitPlot,app.mechUpperDelta,app.mechLowerDelta,app.Collision1,...
                 app.Collision2,app.Collision3,app.NumHolderLid,app.DoubleCase,app.inevitableCollision] = ...
                 plotBiopsyTarget_App(app.plotInicialization,app.P_c,app.SizeHolderCup,app.U,app.V,app.NumGridRow,app.RobotData,app.HolderData,app.HolderModel,app.RotateHolder);
                
                app.numLeadTargetLabel.Text = string(app.NumHolderLid);
                
                % Showing Breast holder device collisions
                if app.Collision2
                    app.BreastHolderCollisionPathLamp.Color = [1,0.5,0];%red: warning
                else
                    app.BreastHolderCollisionPathLamp.Color = [0,1,0];%green: valided target
                end
                
                app.PlotInsertionLamp.Color = [0,1,0];
            catch ME
                disp(ME.message);
                app.PlotInsertionLamp.Color = [1,0,0];%red: error
            end
        end

        % Value changed function: CupSizeDropDown
        function CupSizeDropDownValueChanged(app, event)
            % A new breast holder size demands a new random target sample
            app.getInitialBiopsyData = false;
            app.CountTarget = 1;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BreastBiopsyAppUIFigure = uifigure('Visible', 'off');
            app.BreastBiopsyAppUIFigure.Name = 'BreastBiopsyApp';
            app.BreastBiopsyAppUIFigure.Icon = 'logoApp.png';
            app.BreastBiopsyAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @BreastBiopsyAppUIFigureCloseRequest, true);
            app.BreastBiopsyAppUIFigure.Tag = 'breastBiopsy';
            app.TabGroup = uitabgroup(app.BreastBiopsyAppUIFigure);
            app.GettingBiopsyTargetTab = uitab(app.TabGroup);
            app.GettingBiopsyTargetTab.Title = 'Getting Biopsy Target';
            app.plotInicialization = uiaxes(app.GettingBiopsyTargetTab);
            title(app.plotInicialization, 'Biopsy Target')
            zlabel(app.plotInicialization, 'Z')
            app.BiopsyTargetPanel = uipanel(app.GettingBiopsyTargetTab);
            app.BiopsyTargetPanel.Title = 'Biopsy Target';
            app.PositionLabel = uilabel(app.BiopsyTargetPanel);
            app.PositionLabel.Text = 'Position';
            app.XmmLabel = uilabel(app.BiopsyTargetPanel);
            app.XmmLabel.Text = 'X (mm)';
            app.YmmLabel = uilabel(app.BiopsyTargetPanel);
            app.YmmLabel.Text = 'Y (mm)';
            app.ZmmLabel = uilabel(app.BiopsyTargetPanel);
            app.ZmmLabel.Text = 'Z (mm)';
            app.xTLabel = uilabel(app.BiopsyTargetPanel);
            app.xTLabel.Text = '---';
            app.yTLabel = uilabel(app.BiopsyTargetPanel);
            app.yTLabel.Text = '---';
            app.zTLabel = uilabel(app.BiopsyTargetPanel);
            app.zTLabel.Text = '---';
            app.BreastHolderPanel = uipanel(app.GettingBiopsyTargetTab);
            app.BreastHolderPanel.Title = 'Breast Holder';
            app.CupSizeLabel = uilabel(app.BreastHolderPanel);
            app.CupSizeLabel.Text = 'Cup Size:';
            app.CupSizeDropDown = uidropdown(app.BreastHolderPanel);
            app.CupSizeDropDown.Items = {'A', 'B', 'C', 'D'};
            app.CupSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @CupSizeDropDownValueChanged, true);
            app.CupSizeDropDown.Value = 'A';
            app.HolderLeadLabel = uilabel(app.BreastHolderPanel);
            app.HolderLeadLabel.Text = 'Holder Lead:';
            app.numLeadTargetLabel = uilabel(app.BreastHolderPanel);
            app.numLeadTargetLabel.Text = '---';
            app.Label_8 = uilabel(app.BreastHolderPanel);
            app.Label_8.Text = '#';
            app.RotateButton = uibutton(app.BreastHolderPanel, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.TargetStatePanel = uipanel(app.GettingBiopsyTargetTab);
            app.TargetStatePanel.Title = 'Target State';
            app.BreastHolderCollisionPathLampLabel = uilabel(app.TargetStatePanel);
            app.BreastHolderCollisionPathLampLabel.Text = 'Breast Holder Collision Path';
            app.BreastHolderCollisionPathLamp = uilamp(app.TargetStatePanel);
            app.DetailsButton = uibutton(app.TargetStatePanel, 'push');
            app.DetailsButton.ButtonPushedFcn = createCallbackFcn(app, @DetailsButtonPushed, true);
            app.DetailsButton.Text = 'Details';
            app.PerformPanel = uipanel(app.GettingBiopsyTargetTab);
            app.PerformPanel.Title = 'Perform';
            app.SetTargetButton = uibutton(app.PerformPanel, 'push');
            app.SetTargetButton.ButtonPushedFcn = createCallbackFcn(app, @SetTargetButtonPushed, true);
            app.SetTargetButton.Text = 'Set Target';
            app.PlotInsertionButton = uibutton(app.PerformPanel, 'push');
            app.PlotInsertionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInsertionButtonPushed, true);
            app.PlotInsertionButton.Text = 'Plot Insertion';
            app.GetTargetButton = uibutton(app.PerformPanel, 'push');
            app.GetTargetButton.ButtonPushedFcn = createCallbackFcn(app, @GetTargetButtonPushed, true);
            app.GetTargetButton.Text = 'Get Target';
            app.GetTargetLamp = uilamp(app.PerformPanel);
            app.PlotInsertionLamp = uilamp(app.PerformPanel);
            app.SetTargetLamp = uilamp(app.PerformPanel);
            app.PreplacementstageTab = uitab(app.TabGroup);
            app.PreplacementstageTab.Title = 'Preplacement stage';
            app.plotPrePlacement = uiaxes(app.PreplacementstageTab);
            title(app.plotPrePlacement, 'Robot Pre-Placement')
            zlabel(app.plotPrePlacement, 'Z')
            app.SafeNeedlePlacementPanel = uipanel(app.PreplacementstageTab);
            app.SafeNeedlePlacementPanel.Title = 'Safe Needle Placement';
            app.SetSafetyPositionButtonGroup = uibuttongroup(app.SafeNeedlePlacementPanel);
            app.SetSafetyPositionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SetSafetyPositionButtonGroupSelectionChanged, true);
            app.SetSafetyPositionButtonGroup.Title = 'Set Safty Position';
            app.DefaultButton = uiradiobutton(app.SetSafetyPositionButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.ModifyButton = uiradiobutton(app.SetSafetyPositionButtonGroup);
            app.ModifyButton.Text = 'Modify';
            app.OffsetDropDownLabel = uilabel(app.SetSafetyPositionButtonGroup);
            app.OffsetDropDownLabel.Text = 'Offset';
            app.OffsetDropDown = uidropdown(app.SetSafetyPositionButtonGroup);
            app.OffsetDropDown.Items = {'0.5mm', '1mm', '1.5mm', '2mm', '5mm'};
            app.OffsetDropDown.Value = '1mm';
            app.OffsetPlusButton = uibutton(app.SetSafetyPositionButtonGroup, 'push');
            app.OffsetPlusButton.ButtonPushedFcn = createCallbackFcn(app, @OffsetPlusButtonPushed, true);
            app.OffsetPlusButton.Text = '+';
            app.OffsetMinusButton = uibutton(app.SetSafetyPositionButtonGroup, 'push');
            app.OffsetMinusButton.ButtonPushedFcn = createCallbackFcn(app, @OffsetMinusButtonPushed, true);
            app.OffsetMinusButton.Text = '-';
            app.Tx = uilabel(app.SetSafetyPositionButtonGroup);
            app.Tx.Text = 'T (mm)';
            app.TyLabel = uilabel(app.SetSafetyPositionButtonGroup);
            app.TyLabel.Text = '---';
            app.TzLabel = uilabel(app.SetSafetyPositionButtonGroup);
            app.TzLabel.Text = '---';
            app.TxLabel = uilabel(app.SetSafetyPositionButtonGroup);
            app.TxLabel.Text = '---';
            app.RLabel = uilabel(app.SetSafetyPositionButtonGroup);
            app.RLabel.Text = '---';
            app.PLabel = uilabel(app.SetSafetyPositionButtonGroup);
            app.PLabel.Text = '---';
            app.YLabel = uilabel(app.SetSafetyPositionButtonGroup);
            app.YLabel.Text = '---';
            app.Tx_2 = uilabel(app.SetSafetyPositionButtonGroup);
            app.Tx_2.Text = 'RPY';
            app.SafetyDistanceEditFieldLabel = uilabel(app.SetSafetyPositionButtonGroup);
            app.SafetyDistanceEditFieldLabel.Text = 'Safety Distance';
            app.SafetyDistanceEditField = uieditfield(app.SetSafetyPositionButtonGroup, 'numeric');
            app.SafetyDistanceEditField.Limits = [5 30];
            app.SafetyDistanceEditField.ValueDisplayFormat = '%.2f';
            app.SafetyDistanceEditField.Editable = 'off';
            app.SafetyDistanceEditField.Value = 10;
            app.SetPointButton = uibutton(app.SetSafetyPositionButtonGroup, 'push');
            app.SetPointButton.ButtonPushedFcn = createCallbackFcn(app, @SetPointButtonPushed, true);
            app.SetPointButton.Text = 'Set Point';
            app.SetPointLamp = uilamp(app.SetSafetyPositionButtonGroup);
            app.ReachablePointLampLabel = uilabel(app.SetSafetyPositionButtonGroup);
            app.ReachablePointLampLabel.Text = 'Reachable Point';
            app.ReachablePointLamp = uilamp(app.SetSafetyPositionButtonGroup);
            app.EndEffectorDistancePanel = uipanel(app.SafeNeedlePlacementPanel);
            app.EndEffectorDistancePanel.Title = 'End-Effector Distance';
            app.mmLabel_5 = uilabel(app.EndEffectorDistancePanel);
            app.mmLabel_5.Text = 'mm';
            app.EEdistanceEdit = uieditfield(app.EndEffectorDistancePanel, 'numeric');
            app.EEdistanceEdit.ValueDisplayFormat = '%.2f';
            app.SetBiopsyDeviceToolPanel = uipanel(app.SafeNeedlePlacementPanel);
            app.SetBiopsyDeviceToolPanel.Title = 'Set Biopsy Device / Tool';
            app.TypeDropDownLabel = uilabel(app.SetBiopsyDeviceToolPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeNeedleDropDown = uidropdown(app.SetBiopsyDeviceToolPanel);
            app.TypeNeedleDropDown.Items = {'needleFNA', 'needleCNB1', 'needleCNB2'};
            app.TypeNeedleDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeNeedleDropDownValueChanged, true);
            app.TypeNeedleDropDown.Value = 'needleFNA';
            app.NeedleLengthmmLabel = uilabel(app.SetBiopsyDeviceToolPanel);
            app.NeedleLengthmmLabel.Text = 'Needle Length(mm)';
            app.NeedleLengthEditField = uieditfield(app.SetBiopsyDeviceToolPanel, 'numeric');
            app.NeedleLengthEditField.Editable = 'off';
            app.GaugeDropDownLabel = uilabel(app.SetBiopsyDeviceToolPanel);
            app.GaugeDropDownLabel.Text = 'Gauge';
            app.GaugeDropDown = uidropdown(app.SetBiopsyDeviceToolPanel);
            app.GaugeDropDown.Items = {'', ''};
            app.GaugeDropDown.ValueChangedFcn = createCallbackFcn(app, @GaugeDropDownValueChanged, true);
            app.GaugeDropDown.Value = '';
            app.SetDeviceButton = uibutton(app.SetBiopsyDeviceToolPanel, 'push');
            app.SetDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @SetDeviceButtonPushed, true);
            app.SetDeviceButton.Text = 'Set Device';
            app.SetDeviceLamp = uilamp(app.SetBiopsyDeviceToolPanel);
            app.DeadSpacemmLabel = uilabel(app.SetBiopsyDeviceToolPanel);
            app.DeadSpacemmLabel.Text = 'Dead Space (mm)';
            app.DeadSpaceEditField = uieditfield(app.SetBiopsyDeviceToolPanel, 'numeric');
            app.DeadSpaceEditField.Editable = 'off';
            app.DeadSpaceEditField.Value = 8;
            app.ThrowmmDropDownLabel = uilabel(app.SetBiopsyDeviceToolPanel);
            app.ThrowmmDropDownLabel.Text = 'Throw (mm)';
            app.ThrowmmDropDown = uidropdown(app.SetBiopsyDeviceToolPanel);
            app.ThrowmmDropDown.Items = {'10', '20'};
            app.ThrowmmDropDown.ValueChangedFcn = createCallbackFcn(app, @ThrowmmDropDownValueChanged, true);
            app.ThrowmmDropDown.Value = '10';
            app.DeviceDimensionHelpButton = uibutton(app.SetBiopsyDeviceToolPanel, 'push');
            app.DeviceDimensionHelpButton.ButtonPushedFcn = createCallbackFcn(app, @DeviceDimensionHelpButtonPushed, true);
            app.DeviceDimensionHelpButton.Text = '?';
            app.DistanceFromSkintoTargetPanel = uipanel(app.SafeNeedlePlacementPanel);
            app.DistanceFromSkintoTargetPanel.Title = 'Distance From Skin to Target';
            app.mmLabel_4 = uilabel(app.DistanceFromSkintoTargetPanel);
            app.mmLabel_4.Text = 'mm';
            app.NeedleInsertionEdit = uieditfield(app.DistanceFromSkintoTargetPanel, 'numeric');
            app.NeedleInsertionEdit.ValueDisplayFormat = '%.2f';
            app.SimulationSourcePanel = uipanel(app.SafeNeedlePlacementPanel);
            app.SimulationSourcePanel.Title = 'Simulation Source';
            app.ShowModelButton = uibutton(app.SimulationSourcePanel, 'push');
            app.ShowModelButton.ButtonPushedFcn = createCallbackFcn(app, @ShowModelButtonPushed, true);
            app.ShowModelButton.Text = 'Show  Model';
            app.PlotRobotLamp = uilamp(app.SimulationSourcePanel);
            app.ShowEnvButton = uibutton(app.SimulationSourcePanel, 'push');
            app.ShowEnvButton.Text = 'Show Env';
            app.ShowEnvLamp = uilamp(app.SimulationSourcePanel);
            app.BiopsyTrajectoryPlannerPanel = uipanel(app.PreplacementstageTab);
            app.BiopsyTrajectoryPlannerPanel.Title = 'Biopsy Trajectory Planner';
            app.SettingsButton = uibutton(app.BiopsyTrajectoryPlannerPanel, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.CalculateTrajectoryButton = uibutton(app.BiopsyTrajectoryPlannerPanel, 'push');
            app.CalculateTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateTrajectoryButtonPushed, true);
            app.CalculateTrajectoryButton.Text = 'Get Waypoints';
            app.CalculateTrajectoryLamp = uilamp(app.BiopsyTrajectoryPlannerPanel);
            app.TrajectoryStatePanel = uipanel(app.PreplacementstageTab);
            app.TrajectoryStatePanel.Title = 'Trajectory State';
            app.PlotTrajectoriesButton = uibutton(app.TrajectoryStatePanel, 'push');
            app.PlotTrajectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTrajectoriesButtonPushed, true);
            app.PlotTrajectoriesButton.Text = 'Plot Trajectories';
            app.PlotPathButton = uibutton(app.TrajectoryStatePanel, 'push');
            app.PlotPathButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPathButtonPushed, true);
            app.PlotPathButton.Text = 'Plot Path';
            app.TargetPreplacementPanel = uipanel(app.PreplacementstageTab);
            app.TargetPreplacementPanel.Title = 'Target Pre-placement';
            app.DoneLampLabel = uilabel(app.TargetPreplacementPanel);
            app.DoneLampLabel.Text = 'Done';
            app.DoneLamp = uilamp(app.TargetPreplacementPanel);
            app.SimulateButton = uibutton(app.TargetPreplacementPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ErrorTablePrePlacement = uitable(app.PreplacementstageTab);
            app.ErrorTablePrePlacement.ColumnName = {'X(m)'; 'Y(m)'; 'Z(m)'; 'Error'};
            app.ErrorTablePrePlacement.RowName = {};
            app.HomeViewButton = uibutton(app.PreplacementstageTab, 'push');
            app.HomeViewButton.ButtonPushedFcn = createCallbackFcn(app, @HomeViewButtonPushed, true);
            app.HomeViewButton.Text = 'Home View';
            app.PostplacementstageTab = uitab(app.TabGroup);
            app.PostplacementstageTab.Title = 'Postplacement stage';
            app.ResultsPanel = uipanel(app.PostplacementstageTab);
            app.ResultsPanel.Title = 'Results';
            app.TableResultspreXYZ = uitable(app.ResultsPanel);
            app.TableResultspreXYZ.ColumnName = {'Xi'; 'Yi'; 'Zi'; 'Xf'; 'Yf'; 'Zf'; 'preError'};
            app.TableResultspreXYZ.RowName = {};
            app.TableResultspreRPY = uitable(app.ResultsPanel);
            app.TableResultspreRPY.ColumnName = {'Ri'; 'Pi'; 'Yi'; 'Rf'; 'Pf'; 'Yf'; 'preErrorAngle'};
            app.TableResultspreRPY.RowName = {};
            app.TableResultspostXYZ = uitable(app.ResultsPanel);
            app.TableResultspostXYZ.ColumnName = {'Xi'; 'Yi'; 'Zi'; 'Xf'; 'Yf'; 'Zf'; 'postError'};
            app.TableResultspostXYZ.RowName = {};
            app.TableResultspostRPY = uitable(app.ResultsPanel);
            app.TableResultspostRPY.ColumnName = {'Ri'; 'Pi'; 'Yi'; 'Rf'; 'Pf'; 'Yf'; 'postErrorAngle'};
            app.TableResultspostRPY.RowName = {};
            app.TableResultspostRPY.Tag = 'resuls in mm';
            app.PreplacementLabel = uilabel(app.ResultsPanel);
            app.PreplacementLabel.Text = 'Pre-placement ';
            app.PostplacementLabel = uilabel(app.ResultsPanel);
            app.PostplacementLabel.Text = 'Post-placement ';
            app.DataManagementPanel = uipanel(app.PostplacementstageTab);
            app.DataManagementPanel.Title = 'Data Management';
            app.ReadVariablesButtonSIM = uibutton(app.DataManagementPanel, 'push');
            app.ReadVariablesButtonSIM.ButtonPushedFcn = createCallbackFcn(app, @SaveVariablesButtonSIMPushed, true);
            app.ReadVariablesButtonSIM.Text = 'Save Variables';
            app.ReloadVariablesButton = uibutton(app.DataManagementPanel, 'push');
            app.ReloadVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadVariablesButtonPushed, true);
            app.ReloadVariablesButton.Text = 'Reload Variables';
            app.SimulinkmodelDropDownLabel = uilabel(app.DataManagementPanel);
            app.SimulinkmodelDropDownLabel.Text = 'Simulink model';
            app.SimulinkmodelDropDown = uidropdown(app.DataManagementPanel);
            app.SimulinkmodelDropDown.Items = {'Model1', 'Model2'};
            app.SimulinkmodelDropDown.Value = 'Model1';
            app.OpenModelButton = uibutton(app.DataManagementPanel, 'push');
            app.OpenModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenModelButtonPushed, true);
            app.OpenModelButton.Text = 'Open Model';
            app.OpenModelLamp = uilamp(app.DataManagementPanel);
            app.saveVariablesLamp = uilamp(app.DataManagementPanel);
            app.LoadVariablesLamp = uilamp(app.DataManagementPanel);
            app.SimulationControlPanel = uipanel(app.PostplacementstageTab);
            app.SimulationControlPanel.Title = 'Simulation Control';
            app.SimulateButtonSIM = uibutton(app.SimulationControlPanel, 'push');
            app.SimulateButtonSIM.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonSIMPushed, true);
            app.SimulateButtonSIM.Text = 'Simulate';
            app.StopButtonSIM = uibutton(app.SimulationControlPanel, 'push');
            app.StopButtonSIM.ButtonPushedFcn = createCallbackFcn(app, @StopButtonSIMPushed, true);
            app.StopButtonSIM.Text = 'Stop';
            app.GetResultsButton = uibutton(app.SimulationControlPanel, 'push');
            app.GetResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GetResultsButtonPushed, true);
            app.GetResultsButton.Text = 'Get Results';
            app.SimulateStateLamp = uilamp(app.SimulationControlPanel);
            app.SettingsButton_2 = uibutton(app.SimulationControlPanel, 'push');
            app.SettingsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton_2.Text = 'Settings';
            app.SuccsessfulBiopsyLampLabel = uilabel(app.SimulationControlPanel);
            app.SuccsessfulBiopsyLampLabel.Text = 'Succsessful Biopsy';
            app.SuccsessfulBiopsyLamp = uilamp(app.SimulationControlPanel);
            app.Image = uiimage(app.PostplacementstageTab);
            app.Image.ImageSource = 'espacioTrabajo.png';
            app.RobotGuidedBreastBiopsyGUILabel = uilabel(app.BreastBiopsyAppUIFigure);
            app.RobotGuidedBreastBiopsyGUILabel.Text = 'Robot Guided Breast Biopsy GUI';
            % Show the figure after all components are created
            app.BreastBiopsyAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = biopsyControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BreastBiopsyAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BreastBiopsyAppUIFigure)
        end
    end
end