classdef targetSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InsertionAngleUIFigure          matlab.ui.Figure
        SelectingnewneedleinsetionanglePanel  matlab.ui.container.Panel
        SetZeroButton                   matlab.ui.control.Button
        OptionPanel                     matlab.ui.container.Panel
        GetAngleRangeButton             matlab.ui.control.Button
        SelectInsertionSideButtonGroup  matlab.ui.container.ButtonGroup
        InferiorButton                  matlab.ui.control.RadioButton
        SuperiorButton                  matlab.ui.control.RadioButton
        UpdatePlotButton                matlab.ui.control.Button
        SendAngleButton                 matlab.ui.control.Button
        AngleinradEditField             matlab.ui.control.NumericEditField
        AngleinradEditFieldLabel        matlab.ui.control.Label
        DefineanewinsertionangleKnob    matlab.ui.control.Knob
        DefineanewinsertionangleKnobLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        angle; %angle offset
        optionInsertionSide;
        minAngle;
        maxAngle;
        disableOptionButton
        inputCase;
    end
    
    properties (Access = private)
        callingApp; % Main app
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, inferiorLimit, superiorLimit, disableOptionMultiple)
            %Getting needed data from main GUI
            % Positive angle goes to CCW
            app.callingApp = mainApp;
            app.minAngle = inferiorLimit;
            app.maxAngle = superiorLimit;
            app.disableOptionButton = disableOptionMultiple;
            %app.inputCase = inputAngleCase;%define the operation of input data
            app.optionInsertionSide = 1;%default selection
            app.angle = 0;
            app.DefineanewinsertionangleKnob.Limits = [app.minAngle,app.maxAngle];
            app.AngleinradEditField.Value = app.minAngle;
            if disableOptionMultiple
                app.OptionPanel.Enable = "on";
            else
                app.OptionPanel.Enable = "off";
            end
        end

        % Value changing function: DefineanewinsertionangleKnob
        function DefineanewinsertionangleKnobValueChanging(app, event)
            app.angle = event.Value;
            app.AngleinradEditField.Value = app.angle;          
        end

        % Button pushed function: SendAngleButton
        function SendAngleButtonPushed(app, event)
            app.callingApp.SetTargetButton.Enable = 'on';
            delete(app)
        end

        % Close request function: InsertionAngleUIFigure
        function InsertionAngleUIFigureCloseRequest(app, event)
            app.callingApp.SetTargetButton.Enable = 'on';
            delete(app)
        end

        % Selection changed function: SelectInsertionSideButtonGroup
        function SelectInsertionSideButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectInsertionSideButtonGroup.SelectedObject;
            if isequal(selectedButton.Text,'Superior')
                app.optionInsertionSide = 1;
            else
                app.optionInsertionSide = 2;
            end
        end

        % Button pushed function: GetAngleRangeButton
        function GetAngleRangeButtonPushed(app, event)
            [app.minAngle,app.maxAngle] = sendInsertionSideOption(app.callingApp,app.optionInsertionSide);
            app.DefineanewinsertionangleKnob.Limits = [app.minAngle,app.maxAngle];
            app.angle = app.minAngle;
            app.AngleinradEditField.Value = app.minAngle;
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            %calling to biopsyControl APP
            updatePlotInicialization(app.callingApp,app.optionInsertionSide,app.angle);
            message = sprintf("New insertion point selected");
            uialert(app.InsertionAngleUIFigure,message,'Insertion Point','Icon','success');
        end

        % Button pushed function: SetZeroButton
        function SetZeroButtonPushed(app, event)
            app.AngleinradEditField.Value = 0;
            app.angle = 0;
            app.DefineanewinsertionangleKnob.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InsertionAngleUIFigure = uifigure('Visible', 'off');
            app.InsertionAngleUIFigure.Name = 'InsertionAngle';
            app.InsertionAngleUIFigure.Icon = 'logoApp.png';
            app.InsertionAngleUIFigure.CloseRequestFcn = createCallbackFcn(app, @InsertionAngleUIFigureCloseRequest, true);
            app.InsertionAngleUIFigure.Tag = 'breastBiopsy';
            app.SelectingnewneedleinsetionanglePanel = uipanel(app.InsertionAngleUIFigure);
            app.SelectingnewneedleinsetionanglePanel.Title = 'Selecting new needle insetion angle';
            app.DefineanewinsertionangleKnobLabel = uilabel(app.SelectingnewneedleinsetionanglePanel);
            app.DefineanewinsertionangleKnobLabel.Text = 'Define a new insertion angle';
            app.DefineanewinsertionangleKnob = uiknob(app.SelectingnewneedleinsetionanglePanel, 'continuous');
            app.DefineanewinsertionangleKnob.ValueChangingFcn = createCallbackFcn(app, @DefineanewinsertionangleKnobValueChanging, true);
            app.AngleinradEditFieldLabel = uilabel(app.SelectingnewneedleinsetionanglePanel);
            app.AngleinradEditFieldLabel.Text = 'Angle in rad.';
            app.AngleinradEditField = uieditfield(app.SelectingnewneedleinsetionanglePanel, 'numeric');
            app.AngleinradEditField.Editable = 'off';
            app.SendAngleButton = uibutton(app.SelectingnewneedleinsetionanglePanel, 'push');
            app.SendAngleButton.ButtonPushedFcn = createCallbackFcn(app, @SendAngleButtonPushed, true);
            app.SendAngleButton.Text = 'Send Angle';
            app.UpdatePlotButton = uibutton(app.SelectingnewneedleinsetionanglePanel, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.OptionPanel = uipanel(app.SelectingnewneedleinsetionanglePanel);
            app.OptionPanel.Title = 'Option: Shortest Distance in Collision';
            app.SelectInsertionSideButtonGroup = uibuttongroup(app.OptionPanel);
            app.SelectInsertionSideButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectInsertionSideButtonGroupSelectionChanged, true);
            app.SelectInsertionSideButtonGroup.Title = 'Select Insertion Side';
            app.SuperiorButton = uiradiobutton(app.SelectInsertionSideButtonGroup);
            app.SuperiorButton.Text = 'Superior';
            app.SuperiorButton.Value = true;
            app.InferiorButton = uiradiobutton(app.SelectInsertionSideButtonGroup);
            app.InferiorButton.Text = 'Inferior';
            app.GetAngleRangeButton = uibutton(app.OptionPanel, 'push');
            app.GetAngleRangeButton.ButtonPushedFcn = createCallbackFcn(app, @GetAngleRangeButtonPushed, true);
            app.GetAngleRangeButton.Text = 'Get Angle Range';
            app.SetZeroButton = uibutton(app.SelectingnewneedleinsetionanglePanel, 'push');
            app.SetZeroButton.ButtonPushedFcn = createCallbackFcn(app, @SetZeroButtonPushed, true);
            app.SetZeroButton.Text = 'Set Zero';
            % Show the figure after all components are created
            app.InsertionAngleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = targetSelection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InsertionAngleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InsertionAngleUIFigure)
        end
    end
end